classdef drone_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BLEConnectButton              matlab.ui.control.Button
        ComStatusLampLabel            matlab.ui.control.Label
        ComStatusLamp                 matlab.ui.control.Lamp
        AltitudeSetcmSliderLabel      matlab.ui.control.Label
        AltitudeSetcmSlider           matlab.ui.control.Slider
        CurrentAltiutudecmGaugeLabel  matlab.ui.control.Label
        CurrentAltiutudecmGauge       matlab.ui.control.LinearGauge
        RollGaugeLabel                matlab.ui.control.Label
        RollGauge                     matlab.ui.control.SemicircularGauge
        PitchGaugeLabel               matlab.ui.control.Label
        PitchGauge                    matlab.ui.control.SemicircularGauge
        YawGaugeLabel                 matlab.ui.control.Label
        YawGauge                      matlab.ui.control.SemicircularGauge
        UncalibratedLabel             matlab.ui.control.Label
        FlyButton                     matlab.ui.control.StateButton
        StreamStatesButton            matlab.ui.control.StateButton
        CalibrateButton               matlab.ui.control.StateButton
        LandedLabel                   matlab.ui.control.Label
        StandbyLabel                  matlab.ui.control.Label
        LooptimemsGaugeLabel          matlab.ui.control.Label
        LooptimemsGauge               matlab.ui.control.NinetyDegreeGauge
    end

    
    properties (Access = private)
        drone % Description
        rx_characteristic   %characteristic which gives input bleuart data
        tx_characteristic   %characteristic for writing data
    end
    
    methods (Access = private)
        function calibration_done_ex(app)
            disp("calibration done");
            app.CalibrateButton.Value = 0;  %unclick calib button
            app.UncalibratedLabel.Text = "Calibrated";  %change status text to calibrated
            app.UncalibratedLabel.FontColor = [0 1 0];  %change status text to green
            %ungreyother actions
            app.FlyButton.FontColor = [0 0 0];
            app.StreamStatesButton.FontColor = [0 0 0];      
        end
        
        function landed_ex(app)
            disp("landed");
            app.FlyButton.Value = 0;
            app.LandedLabel.Text = "Landed";
            app.LandedLabel.FontColor = [0 0 0];
            app.CalibrateButton.FontColor = [0 0 0];
            app.StreamStatesButton.FontColor = [0 0 0];
        end
        
        function rx_data_callback(app,src)
            in_data = char(read(app.rx_characteristic,'oldest'));   %put into char array
            disp(in_data);
            switch in_data(1)
                case 'c'    %it is a command
                    
                    switch in_data(2)
                        case '1'  %done calibration
                            app.calibration_done_ex();
                        case '2'  %landed
                            app.landed_ex(); 
                    end
                    
                case 's'    %it is state data
                    in_data_split = split(in_data);
                    values = str2double(in_data_split(2:6));
                    app.RollGauge.Value = values(1)*180/pi;         %rad -> deg
                    app.PitchGauge.Value = values(2)*180/pi;
                    app.YawGauge.Value = values(3)*180/pi;
                    app.CurrentAltiutudecmGauge.Value = values(4);  %in cm
                    app.LooptimemsGauge.Value = values(5);
                
                case 'd'        %debug msg
                    %print the debug msg in the matlab terminal
                    disp(in_data);
                    
            end
            
            
        end
        
        function retVal = is_connected(app)
            if app.ComStatusLamp.Color == [0 1 0]
                retVal = 1;
            else
                retVal = 0;
            end
        
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear
            disp(" ")
            disp(" ")
            disp(" ")
            disp("Starting drone app")
            disp("Get 'er done")
            disp("--------------------------------------------------")
            
        end

        % Button pushed function: BLEConnectButton
        function BLEConnectButtonPushed(app, event)

            app.drone = ble("Drone");
            service_UUID = "6E400001-B5A3-F393-E0A9-E50E24DCCA9E";
            rx_characteristic_UUID = "6E400003-B5A3-F393-E0A9-E50E24DCCA9E";
            app.rx_characteristic = characteristic(app.drone, service_UUID, rx_characteristic_UUID);
            app.rx_characteristic.DataAvailableFcn = @(obj,event)app.rx_data_callback(obj);
            %create tx characteristic
            tx_characteristic_UUID = "6E400002-B5A3-F393-E0A9-E50E24DCCA9E";
            app.tx_characteristic = characteristic(app.drone, service_UUID, tx_characteristic_UUID);
            app.ComStatusLamp.Color = [0 1 0];
            disp("BLE com established")

        end

        % Value changed function: AltitudeSetcmSlider
        function AltitudeSetcmSliderValueChanged(app, event)
            value = app.AltitudeSetcmSlider.Value;
            value = round(value/10); %in code format 4-8
            outputString = strcat("a",int2str(value))
            app.tx_characteristic.write( outputString );%send value
            app.AltitudeSetcmSlider.Value = value*10; %bring back up to x10 for display
        end

        % Value changed function: CalibrateButton
        function CalibrateButtonValueChanged(app, event)
            value = app.CalibrateButton.Value;
            
            %make sure drone is not flying or streaming data
            calib_ready = app.FlyButton.Value==0 & app.StreamStatesButton.Value==0;
            
            %if input value is 0, not normal since it should only stop
            %calib from drone signal
            if value == 0 
                disp("must wait for calibration to be finished")
                app.CalibrateButton.Value = 1;
            elseif value == 1 & ~app.is_connected()
                disp("not connected to ble")
                app.CalibrateButton.Value = 0;
            elseif value == 1 & ~calib_ready
                app.CalibrateButton.Value = 0;
                if(app.FlyButton.Value==1)
                    disp("Cannot calibrate while flying")
                elseif(app.StreamStatesButton.Value==1)
                    disp("Cannot calibrate while streaming states")
                end
            
            elseif calib_ready & app.is_connected()
                disp("Sending calibration command")
                %send calibrate command to drone
                app.tx_characteristic.write("c1");
                %status is put to calibrating and grey color
                app.UncalibratedLabel.Text = "Calibrating...";
                app.UncalibratedLabel.FontColor = [0.5 0.5 0.5];
                %make other actions grayed out
                app.FlyButton.FontColor = [0.5 0.5 0.5];
                app.StreamStatesButton.FontColor = [0.5 0.5 0.5];
                
            end
        
            
        end

        % Value changed function: FlyButton
        function FlyButtonValueChanged(app, event)
            value = app.FlyButton.Value;
            
            %button is pressed
            if value==1 
                %if calibration or streaming is occuring, send error
                %message and reset value to 0
                if app.CalibrateButton.Value==1
                    disp("Cannot fly while calibrating, wait for calibration to finish before initiating flight")
                    app.FlyButton.Value = 0;
                elseif app.StreamStatesButton.Value==1
                    disp("Cannot fly while streaming data, stop stream to initiate flight")
                    app.FlyButton.Value = 0;
                elseif app.is_connected() %if not other process, go into flight mode
                    app.FlyButton.Text = 'Land';
                    app.LandedLabel.Text = "Flying";        %set status to flying
                    app.LandedLabel.FontColor = [0 1 0];    %set status text color to green
                    app.tx_characteristic.write("c4")       %send fly command to drone
                    %gray out other actions
                    app.CalibrateButton.FontColor = [0.5 0.5 0.5];
                    app.StreamStatesButton.FontColor = [0.5 0.5 0.5];
                else
                    disp("not connected to ble")
                    app.FlyButton.Value = 0;
                end
                
            elseif app.is_connected()    %value==0 -> send land command
                app.LandedLabel.Text = "Landing";       %set status text to landing
                app.LandedLabel.FontColor = [1 0 0];    %set status text to red
                app.tx_characteristic.write("c5");      %send land command to drone
                app.FlyButton.Value = 1;                %still is set to 1 until landed
                
            end


        end

        % Value changed function: StreamStatesButton
        function StreamStatesButtonValueChanged(app, event)
            value = app.StreamStatesButton.Value;
            
            if value==1
                %check if other actions are occuring
                if app.CalibrateButton.Value==1
                    disp("Cannot stream while calibrating, wait for calibration to finish before initiating streaming")
                    app.StreamStatesButton.Value = 0;
                elseif app.FlyButton.Value==1
                    disp("Cannot stream while flying data, stop flying to initiate streaming")
                    app.StreamStatesButton.Value = 0;
                elseif app.is_connected()
                    disp("starting streaming...")
                    disp("----------------------")
                    app.CalibrateButton.FontColor = [0.5 0.5 0.5];
                    app.FlyButton.FontColor = [0.5 0.5 0.5];
                    app.StandbyLabel.Text = "Streaming";
                    app.StreamStatesButton.Text = "Stop Streaming";
                    app.tx_characteristic.write("c2");
                else
                    disp("not connected to ble")
                    app.StreamStatesButton.Value = 0;
                end
                
            elseif app.is_connected()  %value==0, then stop streaming
            
                app.CalibrateButton.FontColor = [0 0 0];
                app.FlyButton.FontColor = [0 0 0];
                app.StandbyLabel.Text = "Standby";
                app.StreamStatesButton.Text = "Stream States";
                app.tx_characteristic.write("c3");
                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BLEConnectButton = uibutton(app.UIFigure, 'push');
            app.BLEConnectButton.ButtonPushedFcn = createCallbackFcn(app, @BLEConnectButtonPushed, true);
            app.BLEConnectButton.Text = 'BLE Connect';
            app.ComStatusLampLabel = uilabel(app.UIFigure);
            app.ComStatusLampLabel.Text = 'Com Status';
            app.ComStatusLamp = uilamp(app.UIFigure);
            app.AltitudeSetcmSliderLabel = uilabel(app.UIFigure);
            app.AltitudeSetcmSliderLabel.Text = 'Altitude Set [cm]';
            app.AltitudeSetcmSlider = uislider(app.UIFigure);
            app.AltitudeSetcmSlider.Limits = [30 80];
            app.AltitudeSetcmSlider.MajorTicks = [40 50 60 70 80];
            app.AltitudeSetcmSlider.ValueChangedFcn = createCallbackFcn(app, @AltitudeSetcmSliderValueChanged, true);
            app.AltitudeSetcmSlider.MinorTicks = [];
            app.AltitudeSetcmSlider.Value = 30;
            app.CurrentAltiutudecmGaugeLabel = uilabel(app.UIFigure);
            app.CurrentAltiutudecmGaugeLabel.Text = 'Current Altiutude [cm]';
            app.CurrentAltiutudecmGauge = uigauge(app.UIFigure, 'linear');
            app.CurrentAltiutudecmGauge.Limits = [30 80];
            app.RollGaugeLabel = uilabel(app.UIFigure);
            app.RollGaugeLabel.Text = 'Roll';
            app.RollGauge = uigauge(app.UIFigure, 'semicircular');
            app.RollGauge.Limits = [-45 45];
            app.PitchGaugeLabel = uilabel(app.UIFigure);
            app.PitchGaugeLabel.Text = 'Pitch';
            app.PitchGauge = uigauge(app.UIFigure, 'semicircular');
            app.PitchGauge.Limits = [-90 90];
            app.YawGaugeLabel = uilabel(app.UIFigure);
            app.YawGaugeLabel.Text = 'Yaw';
            app.YawGauge = uigauge(app.UIFigure, 'semicircular');
            app.YawGauge.Limits = [-90 90];
            app.UncalibratedLabel = uilabel(app.UIFigure);
            app.UncalibratedLabel.Text = 'Uncalibrated';
            app.FlyButton = uibutton(app.UIFigure, 'state');
            app.FlyButton.ValueChangedFcn = createCallbackFcn(app, @FlyButtonValueChanged, true);
            app.FlyButton.Text = 'Fly';
            app.StreamStatesButton = uibutton(app.UIFigure, 'state');
            app.StreamStatesButton.ValueChangedFcn = createCallbackFcn(app, @StreamStatesButtonValueChanged, true);
            app.StreamStatesButton.Text = 'Stream States';
            app.CalibrateButton = uibutton(app.UIFigure, 'state');
            app.CalibrateButton.ValueChangedFcn = createCallbackFcn(app, @CalibrateButtonValueChanged, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.LandedLabel = uilabel(app.UIFigure);
            app.LandedLabel.Text = 'Landed';
            app.StandbyLabel = uilabel(app.UIFigure);
            app.StandbyLabel.Text = 'Standby';
            app.LooptimemsGaugeLabel = uilabel(app.UIFigure);
            app.LooptimemsGaugeLabel.Text = 'Loop time (ms)';
            app.LooptimemsGauge = uigauge(app.UIFigure, 'ninetydegree');
            app.LooptimemsGauge.Limits = [0 20];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drone_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end